classdef ControlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GreenPlateButton           matlab.ui.control.Button
        EStopButton                matlab.ui.control.Button
        Image_2                    matlab.ui.control.Image
        TabGroup                   matlab.ui.container.TabGroup
        UR3LinksTab                matlab.ui.container.Tab
        Link1KnobLabel             matlab.ui.control.Label
        Link1Knob                  matlab.ui.control.Knob
        Link2KnobLabel             matlab.ui.control.Label
        Link2Knob                  matlab.ui.control.Knob
        Link3KnobLabel             matlab.ui.control.Label
        Link3Knob                  matlab.ui.control.Knob
        Link4KnobLabel             matlab.ui.control.Label
        Link4Knob                  matlab.ui.control.Knob
        Link5KnobLabel             matlab.ui.control.Label
        Link5Knob                  matlab.ui.control.Knob
        Link6KnobLabel             matlab.ui.control.Label
        Link6Knob                  matlab.ui.control.Knob
        PandaLinksTab              matlab.ui.container.Tab
        Link1Knob_2Label           matlab.ui.control.Label
        Link1Knob_2                matlab.ui.control.Knob
        Link2Knob_2Label           matlab.ui.control.Label
        Link2Knob_2                matlab.ui.control.Knob
        Link3Knob_2Label           matlab.ui.control.Label
        Link3Knob_2                matlab.ui.control.Knob
        Link4Knob_2Label           matlab.ui.control.Label
        Link4Knob_2                matlab.ui.control.Knob
        Link5Knob_2Label           matlab.ui.control.Label
        Link5Knob_2                matlab.ui.control.Knob
        Link6Knob_2Label           matlab.ui.control.Label
        Link6Knob_2                matlab.ui.control.Knob
        Link7KnobLabel             matlab.ui.control.Label
        Link7Knob                  matlab.ui.control.Knob
        UR3CartesianControlTab     matlab.ui.container.Tab
        XSliderLabel               matlab.ui.control.Label
        XSlider                    matlab.ui.control.Slider
        YSliderLabel               matlab.ui.control.Label
        YSlider                    matlab.ui.control.Slider
        ZSliderLabel               matlab.ui.control.Label
        ZSlider                    matlab.ui.control.Slider
        PandaCartesianControlTab   matlab.ui.container.Tab
        XSlider_2Label             matlab.ui.control.Label
        XSlider_2                  matlab.ui.control.Slider
        YSlider_2Label             matlab.ui.control.Label
        YSlider_2                  matlab.ui.control.Slider
        ZSlider_2Label             matlab.ui.control.Label
        ZSlider_2                  matlab.ui.control.Slider
        ControlPanelLabel          matlab.ui.control.Label
        ShutDownButton             matlab.ui.control.Button
        Image2                     matlab.ui.control.Image
        ControllerStateLamp        matlab.ui.control.Lamp
        StatewindowEditField       matlab.ui.control.EditField
        EstopTriggerLamp           matlab.ui.control.Lamp
        Image3                     matlab.ui.control.Image
        BlackPlateButton           matlab.ui.control.Button
        ResumeButton               matlab.ui.control.Button
        ObjectPositionButtonGroup  matlab.ui.container.ButtonGroup
        xButton                    matlab.ui.control.RadioButton
        yButton                    matlab.ui.control.RadioButton
        zButton                    matlab.ui.control.RadioButton
        Image4                     matlab.ui.control.Image
        CollisionDetectionButton   matlab.ui.control.StateButton
        Button                     matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        RMRCControlButton          matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        Image5                     matlab.ui.control.Image
    end

     properties (Access = public)
         
        %> UR3 Setup Variables
        ur3; 
        ur3StartQ;
        ur3UpdateQ;
        ur3control;
        ur3finger1;
        ur3finger2;
        ur3centrepoints;
        ur3radii;
        ur3base;
        ur3Link1 = 0;
        ur3Link2 = 0;
        ur3Link3 = 0;
        ur3Link4 = 0;
        ur3Link5 = 0;
        ur3Link6 = 0;
        ur3velocityx = 0;
        ur3velocityy = 0;
        ur3velocityz = 0;
        
        %> Panda Setup Variables
        panda;
        pandaStartQ;
        pandaUpdateQ;
        pandafinger1;
        pandafinger2;
        pandacontrol;
        pandacentrepoints;
        pandaradii;
        pandabase;
        pandaLink1 = 0;
        pandaLink2 = 0;
        pandaLink3 = 0;
        pandaLink4 = 0;
        pandaLink5 = 0;
        pandaLink6 = 0;
        pandaLink7 = 0;
        pandavelocityx = 0;
        pandavelocityy = 0;
        pandavelocityz = 0;
        
        %> Environment Variables
        greenplate;
        gplate_verts;
        gplate_tverts;
        blackplate;
        bplate_verts;
        bplate_tverts;
        kitchen;
        plates;
        plateCount;
        platePosition;
        plateHeight;
        
        %> Motion Variables
        qMatrix;
        ur3collisioncheck; % Check collision for UR3
        pandacollisioncheck; % Check collision for Panda (to be worked on)
        collisionOn;
        collisionObj;
        collisionObjPos;
        collisionDir;
        hand;
        time;
        deltaT;
        lightcurtain;
        ur3T1;
        ur3T2;
        ur3T3;
        ur3T4;
        pandaT1;
        pandaT2;
        pandaT3;
        pandaT4;
        resolvedMotionRateControl;
        
        %> Button callouts
        estop;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            
            % Display initialisation prompts into the panel
            app.StatewindowEditField.Value = "Starting up panel......";
            app.ControllerStateLamp.Color = [0.8 0.8 0.8]; % White
            app.EstopTriggerLamp.Color = [0.7 0.7 0.7]; % Gray
            pause(0.5)
            app.StatewindowEditField.Value = "Loading UI....";

            % Spawn Environment
            app.kitchen = Kitchen(0);          
            app.kitchen.Spawnkitchen(); 
            
            % Plot UR3 
            app.ur3base.position = transl(0.8, -1.85, 1.0) * trotz(pi);
            app.ur3 = NewUR3(app.ur3base.position);
            app.ur3.PlotAndColourUR3();
            
            % Setup Ellipsoid Coordinates for Links
            app.ur3centrepoints = [0.0, 0.0, 0.05; 
                0.0, -0.01, 0.01; 
                0.125, 0.0, 0.125; 
                0.105, 0.0, 0.05; 
                0.0, 0.0, 0.01; 
                0.0, 0.0, 0.06; 
                0.0, 0.0, 0.0;]; 
            
            app.ur3radii = [0.08, 0.09, 0.055; 
                0.075, 0.085, 0.075; 
                0.175, 0.08, 0.085; 
                0.15, 0.06, 0.085; 
                0.04, 0.055, 0.065; 
                0.04, 0.045, 0.125; 
                0.0, 0.0, 0.0;]; 
                        
            % Plot RobotIQ gripper
            ur3_q0 = zeros (1, app.ur3.model.n); % Initial UR3 joint angles
           
            % IQ Finger 1
            app.ur3finger1 = IQFinger1;
            app.ur3finger1.model.base = app.ur3finger1.model.base * app.ur3.model.fkine(ur3_q0) * troty(-pi/2) * transl(0.065,-0.0115,0); % Forward kinematics for end-effector base
            app.ur3finger1.GetAndColourFingerIQ1(); 
            
            % IQ Finger 2
            app.ur3finger2 = IQFinger2;
            app.ur3finger2.model.base = app.ur3finger2.model.base * app.ur3.model.fkine(ur3_q0) * trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
            app.ur3finger2.GetAndColourFingerIQ2();  
            
            % Plot Panda 
            app.pandabase.position = transl(-0.15, -1.45, 0.995);
            app.panda = NewPanda(app.pandabase.position);
            app.panda.PlotAndColourPanda();
            
            app.pandacentrepoints = [-0.025, 0.0, 0.06; 
                0.0, 0.0975, -0.035; 
                0.0, 0.03, 0.075; 
                -0.075, -0.085, 0.0; 
                0.05, 0.0, 0.035; 
                0.0, 0.125, 0.025; 
                -0.05,  0.025, 0.0;]; 
            
            app.pandaradii = [0.135, 0.125, 0.075; 
                0.1, 0.175, 0.1; 
                0.125, 0.12, 0.125; 
                0.075, 0.075, 0.095;
                0.125, 0.125, 0.095; 
                0.085, 0.15, 0.105; 
                0.110, 0.1, 0.085;]; 

            % Green Plate
            app.greenplate = PlaceObject('plate.PLY'); 
            app.gplate_verts = get(app.greenplate, 'Vertices');
 
            app.gplate_tverts = [app.gplate_verts, ones(size(app.gplate_verts, 1),1)] * transl(0.75,-1.525,0.775)';
            set(app.greenplate ,'Vertices',app.gplate_tverts(:,1:3)) 
  
            % Black Plate
            app.blackplate = PlaceObject('plateblack.ply'); 
            app.bplate_verts = get(app.blackplate, 'Vertices');
 
            app.bplate_tverts = [app.bplate_verts, ones(size(app.bplate_verts, 1),1)] * transl(0.75,-1.45,0.775)';
            set(app.blackplate ,'Vertices',app.bplate_tverts(:,1:3)) 
            
            view(170,15); % Rotate the scene
            app.StatewindowEditField.Value = "Successfully connected to Control Panel";
            app.ControllerStateLamp.Color = [0 1 0]; % Green
            
            % Set up robot ellipsoid parameters (for collisions)
            app.ur3collisioncheck = Collisiontest(app.ur3, app.ur3centrepoints, app.ur3radii);
            app.pandacollisioncheck = Collisiontest(app.panda, app.pandacentrepoints, app.pandaradii);
            
            % Set up obstacle for collisions
            app.collisionObjPos = [0.5,0.6,0.5];
            app.collisionObj = Mesh(app.collisionObjPos);
        end

        % Value changing function: Link6Knob
        function Link6KnobValueChanging(app, event)
            changingValue = event.Value;
            app.ur3Link6 = deg2rad(changingValue);
            app.ur3.model.animate([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]);
        end

        % Value changing function: Link5Knob
        function Link5KnobValueChanging(app, event)
            changingValue = event.Value;
            app.ur3Link5 = deg2rad(changingValue);
            app.ur3.model.animate([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]);
        end

        % Value changing function: Link4Knob
        function Link4KnobValueChanging(app, event)
            changingValue = event.Value;
            app.ur3Link4 = deg2rad(changingValue);
            app.ur3.model.animate([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]);
        end

        % Value changing function: Link3Knob
        function Link3KnobValueChanging(app, event)
            changingValue = event.Value;
            app.ur3Link3 = deg2rad(changingValue);
            app.ur3.model.animate([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]);
        end

        % Value changing function: Link2Knob
        function Link2KnobValueChanging(app, event)
            changingValue = event.Value;
            app.ur3Link2 = deg2rad(changingValue);
            app.ur3.model.animate([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]);
        end

        % Value changing function: Link1Knob
        function Link1KnobValueChanging(app, event)
            changingValue = event.Value;
            app.ur3Link1 = deg2rad(changingValue);
            app.ur3.model.animate([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]);
        end

        % Value changing function: Link7Knob
        function Link7KnobValueChanging(app, event)
            changingValue = event.Value;
            app.pandaLink7 = deg2rad(changingValue);
            app.panda.model.animate([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]);
        end

        % Value changing function: Link6Knob_2
        function Link6Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandaLink6 = deg2rad(changingValue);
            app.panda.model.animate([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]);
        end

        % Value changing function: Link5Knob_2
        function Link5Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandaLink5 = deg2rad(changingValue);
            app.panda.model.animate([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]);
        end

        % Value changing function: Link4Knob_2
        function Link4Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandaLink4 = deg2rad(changingValue);
            app.panda.model.animate([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]);
        end

        % Value changing function: Link3Knob_2
        function Link3Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandaLink3 = deg2rad(changingValue);
            app.panda.model.animate([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]);
        end

        % Value changing function: Link2Knob_2
        function Link2Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandaLink2 = deg2rad(changingValue);
            app.panda.model.animate([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]);
        end

        % Value changing function: Link1Knob_2
        function Link1Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandaLink1 = deg2rad(changingValue);
            app.panda.model.animate([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]);
        end

        % Button pushed function: GreenPlateButton
        function GreenPlateButtonPushed(app, event)
            app.StatewindowEditField.Value = "Starting sequence to pickup green plate";
            app.ur3StartQ = [app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]; % Reasonable initial guess for ikcon in control
            app.pandaStartQ = [app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6, app.pandaLink7];
            
            % Animate each joint configuration to the set values from the knobs
            app.ur3.model.animate(app.ur3StartQ);
            app.panda.model.animate(app.pandaStartQ);
            
            % Animate fingers
            iqFingerStart = deg2rad([0 0]);
            iqFingerOpen = deg2rad([-6.5 0]);
            iqFingerClose = deg2rad([22 10.5]);
            
            % Fine Interpolation 
            % UR3
            q1 = deg2rad([45 -27.5 50 70 90 0]);
            q2 = deg2rad([81.5 -25 42.8 59.2 90 0]);
            q3 = deg2rad([81.5 -12 50 51 90 0]);

            qWayPoints = [app.ur3.model.getpos;q1;q2;q3];
            iqOpenWayPoints = [iqFingerStart;iqFingerOpen];
            iqCloseWayPoints = [iqFingerOpen;iqFingerClose];
  
            app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));
            openMatrix = InterpolateWayPointRadians(iqOpenWayPoints, deg2rad(2));
            closeMatrix = InterpolateWayPointRadians(iqCloseWayPoints, deg2rad(2));

            app.ur3finger1.model.animate(openMatrix);
            app.ur3finger2.model.animate(openMatrix); % Open fingers
            drawnow();
            
            for i = 1:size(app.qMatrix,1)
                ur3Q = app.qMatrix(i,:);
                app.ur3.model.animate(ur3Q);
                app.ur3finger1.model.base = app.ur3.model.fkine(ur3Q) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger1.model.animate(app.ur3finger1.model.getpos);   
                app.ur3finger2.model.base = app.ur3.model.fkine(ur3Q)* trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger2.model.animate(app.ur3finger2.model.getpos);
                drawnow();
            end
            
            app.ur3finger1.model.animate(closeMatrix);
            app.ur3finger2.model.animate(closeMatrix); % Close Fingers
            drawnow();
  
            % Pick up and drop plate 
            q4 = deg2rad([81.5 -33.6 64.4 59.2 90 0]);
            q5 = deg2rad([146 -81.0 57.2 93.6 90 86.6]);
            q6 = deg2rad([182 -30.6 -22 112 90 86.6]);
            q7 = deg2rad([182 -23 -22 109 90 86.6]); % ([182 -22 -22 105 90 86.6])
  
            qWayPoints = [q4;q5;q6;q7];
  
            app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));
  
            for i = 1:size(app.qMatrix,1)
                ur3Q = app.qMatrix(i,:);
                app.ur3.model.animate(ur3Q);
      
                app.ur3finger1.model.base = app.ur3.model.fkine(ur3Q) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger1.model.animate(app.ur3finger1.model.getpos);   
                app.ur3finger2.model.base = app.ur3.model.fkine(ur3Q)* trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger2.model.animate(app.ur3finger2.model.getpos); 
      
                tr = app.ur3.model.fkine(ur3Q) * transl(0,0,0.1975); % transl(0,0,0.1925)
                app.gplate_tverts = [app.gplate_verts, ones(size(app.gplate_verts, 1),1)] * tr';
                set(app.greenplate ,'Vertices',app.gplate_tverts(:,1:3)) 

                drawnow();
                pause(0.01);
            end
  
            % Move up after drop
            q8 = deg2rad([182 -23.4 -22 112 90 86.6]);
            q9 = deg2rad([180 -90 0 0 0 0]);
            new_q0 = deg2rad([0 0 0 0 0 0]);

            qWayPoints = [q7;q8;q9;new_q0];
  
            app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));

            app.ur3finger1.model.animate(openMatrix);
            app.ur3finger2.model.animate(openMatrix); % Open fingers
            drawnow();
            
            for i = 1:size(app.qMatrix,1)
                ur3Q = app.qMatrix(i,:);
                app.ur3.model.animate(ur3Q);
                app.ur3finger1.model.base = app.ur3.model.fkine(ur3Q) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger1.model.animate(app.ur3finger2.model.getpos);   
                app.ur3finger2.model.base = app.ur3.model.fkine(ur3Q)* trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger2.model.animate(app.ur3finger2.model.getpos);
                drawnow();
            end
           
            app.StatewindowEditField.Value = "Green plate deposited in rack";  
            
             % Set pickup path
             q1 = deg2rad([-45 50 0 -43.2 0 -104 43.5]);
             q2 = deg2rad([-45 55 0 -57.6 0 -107 43.5]);

             qWayPoints = [app.panda.model.getpos;q1;q2];
  
             app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));

             for i = 1:size(app.qMatrix,1)
                pandaQ = app.qMatrix(i,:);
                app.panda.model.animate(pandaQ);
                drawnow();
             end
   
            % Pick up and drop plate 
            q3 = deg2rad([-45 50 0 -43.2 0 -104 43.5]); 
            q4 = deg2rad([-131 32 0 -43.2 0 -104 130]);
            q5 = deg2rad([-131 60 0 -43.2 97.2 -144 14]); 

            qWayPoints = [q3;q4;q5];
  
            app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));
  
            for i = 1:size(app.qMatrix,1)
                pandaQ = app.qMatrix(i,:);
                app.panda.model.animate(pandaQ);
      
                tr = app.panda.model.fkine(pandaQ) * transl(0,-0.2085,0) * trotz(pi/2) * trotx(-pi/2);
                app.gplate_tverts = [app.gplate_verts, ones(size(app.gplate_verts, 1),1)] * tr';
                set(app.greenplate ,'Vertices',app.gplate_tverts(:,1:3)) 

                drawnow();
                pause(0.01);
            end

            app.gplate_tverts = [app.gplate_verts, ones(size(app.gplate_verts, 1),1)] * transl(-0.725,1.335,2.185)' * trotx(-pi/2);
            set(app.greenplate ,'Vertices',app.gplate_tverts(:,1:3)) 
  
            app.StatewindowEditField.Value = "Green Plate deposited";

            % Repose after drop 
            q7 = deg2rad([-131 32 0 -43.2 0 -104 130]);
            q8 = deg2rad([0 0 0 0 0 0 0]);

            qWayPoints = [q7;q8];
  
            app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));

            for i = 1:size(app.qMatrix,1)
                pandaQ = app.qMatrix(i,:);
                app.panda.model.animate(pandaQ);
                drawnow();
            end
        end

        % Button pushed function: ShutDownButton
        function ShutDownButtonPushed(app, event)
            app.StatewindowEditField.Value = "Shutting system down";
            pause(0.5);
            clf;
            cla;
            clear;
            close all;
            clc;
            closereq;
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
%             app.estop = app.EStopButtonPushed.Value;
            app.StatewindowEditField.Value = "E-stop pushed";
            set(gcf,'color','r'); % Set background color to red
            app.EstopTriggerLamp.Color = [0 1 0]; % Green
            uiwait();
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            app.EStopButtonPushed;
        end

        % Callback function
        function ImageClicked(app, event)
            app.GreenPlateButtonPushed;
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            app.ShutDownButtonPushed;
        end

        % Callback function
        function Image4Clicked(app, event)
            app.ResumeButtonPushed;
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ur3velocityz= 0.15 * changingValue;
            dt = 0.30;
            dx = [0;0;app.ur3velocityz;0;0;0]; % [x,y,z,roll,pitch,yaw]
            q = app.ur3.model.getpos;
            J = app.ur3.model.jacob0(q);
            dq = pinv(J)* dx;
            q = q + dq'*dt;
            app.ur3.model.animate(q);
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ur3velocityy= 0.15 * changingValue;
            dt = 0.30;
            dx = [0;app.ur3velocityy;0;0;0;0]; % [x,y,z,roll,pitch,yaw]
            q = app.ur3.model.getpos;
            J = app.ur3.model.jacob0(q);
            dq = pinv(J)* dx;
            q = q + dq'*dt;
            app.ur3.model.animate(q);
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ur3velocityx= 0.15 * changingValue;
            dt = 0.30;
            dx = [app.ur3velocityx;0;0;0;0;0]; % [x,y,z,roll,pitch,yaw]
            q = app.ur3.model.getpos;
            J = app.ur3.model.jacob0(q);
            dq = pinv(J)* dx;
            q = q + dq'*dt;
            app.ur3.model.animate(q);
        end

        % Value changing function: ZSlider_2
        function ZSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandavelocityz= 0.15 * changingValue;
            dt = 0.30;
            dx = [0;0;app.pandavelocityz;0;0;0]; % [x,y,z,roll,pitch,yaw]
            q = app.panda.model.getpos;
            J = app.panda.model.jacob0(q);
            dq = pinv(J) * dx;
            q = q + dq'*dt;
            app.panda.model.animate(q);
        end

        % Value changing function: YSlider_2
        function YSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandavelocityy= 0.15 * changingValue;
            dt = 0.30;
            dx = [0;app.pandavelocityy;0;0;0;0]; % [x,y,z,roll,pitch,yaw]
            q = app.panda.model.getpos;
            J = app.panda.model.jacob0(q);
            dq = pinv(J)* dx;
            q = q + dq'*dt;
            app.panda.model.animate(q);
        end

        % Value changing function: XSlider_2
        function XSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandavelocityx= 0.15 * changingValue;
            dt = 0.30;
            dx = [app.pandavelocityx;0;0;0;0;0];
            q = app.panda.model.getpos;
            J = app.panda.model.jacob0(q);
            dq = pinv(J)* dx;
            q = q + dq'*dt;
            app.panda.model.animate(q);
        end

        % Callback function
        function EmergencyStopToggleSwitchValueChanged(app, event)
%             value = app.EmergencyStopToggleSwitch.Value;
% %             if strcmp(value, 'On') % If switch is on
% %                 (state == 1  && self.safety.guiEstop == 0) || (state == 1  &&  self.safety.hardwareEstop == 0)
% %                 self.safety.emergencyStopState = 1;
% %                 self.safetyLEDS{1}.BackgroundColor = 'Red';
% %                 self.safetyJog = 0;
% %             elseif state == 0 && self.safetyJog == 1 && self.safety.guiEstop == 0 && self.safety.hardwareEstop == 0
% %                 self.safety.emergencyStopState = 0;
% %                 self.safetyLEDS{1}.BackgroundColor = 'Green';
% %             end
% %         end
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed2(app, event)
            app.StatewindowEditField.Value = "Resuming operations...";
            set(gcf,'color','w'); % Set background color to red
            app.EstopTriggerLamp.Color = [0.7 0.7 0.7]; % Gray
            uiresume();
        end

        % Callback function
        function CollisionDetectionButtonPushed(app, event)
            
            function cartJogCube(self,dir)
            % same as for robot but with cube
            currentPosition = self.cube.position;
            desiredPosition = currentPosition;
            switch(dir)
                case 'x+'
                    desiredPosition(1) = desiredPosition(1) + self.cartJoggingDelta;
                case 'x-'
                    desiredPosition(1) = desiredPosition(1) - self.cartJoggingDelta;
                case 'y+'
                    desiredPosition(2) = desiredPosition(2) + self.cartJoggingDelta;
                case 'y-'
                    desiredPosition(2) = desiredPosition(2) - self.cartJoggingDelta;
                case 'z+'
                    desiredPosition(3) = desiredPosition(3) + self.cartJoggingDelta;
                case 'z-'
                    desiredPosition(3) = desiredPosition(3) - self.cartJoggingDelta;
            end
            
            if self.collisionOn
                self.cube.move(desiredPosition);
            end
        end
        end

        % Value changed function: CollisionDetectionButton
        function CollisionDetectionButtonValueChanged(app, event)
            event = app.CollisionDetectionButton.Value;
            if event == 1
                event.Value = 0;
                app.StatewindowEditField.Value = "Collision Detection Active";
                app.collisionOn = 1;
                app.collisionObj.updatePlot();
            else
                event.Value = 1;
                app.collisionOn = 0;
                app.collisionObj.removePlots;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % + Movement of Cube
            data = [app.collisionDir, event.String];
            app.cartJogCube(data);
        end

        % Selection changed function: ObjectPositionButtonGroup
        function ObjectPositionButtonGroupSelectionChanged(app, event)
%             if app.xButton.Value == 1
%             selectedButton = app.ObjectPositionButtonGroup.SelectedObject;
            buttonsel = event.String;
            for i = 1:size(app.ObjectPositionButtonGroup,2)
                if app.ObjectPositionButtonGroup{i}.String ~= buttonsel
                   app.ObjectPositionButtonGroup{i}.Value = 0;
                end
            end
            app.collisionDir = event.String;
        end

        % Button pushed function: BlackPlateButton
        function BlackPlateButtonPushed(app, event)
            app.StatewindowEditField.Value = "Starting sequence to pickup black plate";
            app.ur3StartQ = [app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]; % Reasonable initial guess for ikcon in control
            app.pandaStartQ = [app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6, app.pandaLink7];
            
            % Animate each joint configuration to the set values from the knobs
            app.ur3.model.animate(app.ur3StartQ);
            app.panda.model.animate(app.pandaStartQ);
            
            % Animate fingers
            iqFingerStart = deg2rad([0 0]);
            iqFingerOpen = deg2rad([-6.5 0]);
            iqFingerClose = deg2rad([22 10.5]);
            
            % Fine Interpolation 
            % UR3
            q1 = deg2rad([45 -27.5 50 70 90 0]);
            q2 = deg2rad([81.5 -20.4 28.4 59.2 90 0]);
            q3 = deg2rad([81.5 -6 28.4 51 90 0]);

            qWayPoints = [app.ur3.model.getpos;q1;q2;q3];
            iqOpenWayPoints = [iqFingerStart;iqFingerOpen];
            iqCloseWayPoints = [iqFingerOpen;iqFingerClose];
  
            app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));
            openMatrix = InterpolateWayPointRadians(iqOpenWayPoints, deg2rad(2));
            closeMatrix = InterpolateWayPointRadians(iqCloseWayPoints, deg2rad(2));

            app.ur3finger1.model.animate(openMatrix);
            app.ur3finger2.model.animate(openMatrix); % Open fingers
            drawnow();
            
            for i = 1:size(app.qMatrix,1)
                ur3Q = app.qMatrix(i,:);
                app.ur3.model.animate(ur3Q);
                app.ur3finger1.model.base = app.ur3.model.fkine(ur3Q) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger1.model.animate(app.ur3finger1.model.getpos);   
                app.ur3finger2.model.base = app.ur3.model.fkine(ur3Q)* trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger2.model.animate(app.ur3finger2.model.getpos);
                drawnow();
            end
            
            app.ur3finger1.model.animate(closeMatrix);
            app.ur3finger2.model.animate(closeMatrix); % Close Fingers
            drawnow();
  
            % Pick up and drop plate 
            q4 = deg2rad([81.5 -20.4 28.4 59.2 90 0]);
            q5 = deg2rad([168 -81.0 57.2 93.6 90 86.6]);
            q6 = deg2rad([189 -37.8 -7.6 112 90 86.6]);
            q7 = deg2rad([189 -20 -14.8 112 90 86.6]); 
  
            qWayPoints = [q4;q5;q6;q7];
  
            app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));
  
            for i = 1:size(app.qMatrix,1)
                ur3Q = app.qMatrix(i,:);
                app.ur3.model.animate(ur3Q);
      
                app.ur3finger1.model.base = app.ur3.model.fkine(ur3Q) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger1.model.animate(app.ur3finger1.model.getpos);   
                app.ur3finger2.model.base = app.ur3.model.fkine(ur3Q)* trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger2.model.animate(app.ur3finger2.model.getpos); 
      
                tr = app.ur3.model.fkine(ur3Q) * transl(0,0,0.1975); 
                app.bplate_tverts = [app.bplate_verts, ones(size(app.bplate_verts, 1),1)] * tr';
                set(app.blackplate ,'Vertices',app.bplate_tverts(:,1:3)) 

                drawnow();
                pause(0.01);
            end
  
            % Move up after drop
            q8 = deg2rad([182 -23.4 -22 112 90 86.6]);
            q9 = deg2rad([180 -90 0 0 0 0]);
            new_q0 = deg2rad([0 0 0 0 0 0]);

            qWayPoints = [q7;q8;q9;new_q0];
  
            app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));

            app.ur3finger1.model.animate(openMatrix);
            app.ur3finger2.model.animate(openMatrix); % Open fingers
            drawnow();
            
            for i = 1:size(app.qMatrix,1)
                ur3Q = app.qMatrix(i,:);
                app.ur3.model.animate(ur3Q);
                app.ur3finger1.model.base = app.ur3.model.fkine(ur3Q) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger1.model.animate(app.ur3finger2.model.getpos);   
                app.ur3finger2.model.base = app.ur3.model.fkine(ur3Q)* trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger2.model.animate(app.ur3finger2.model.getpos);
                drawnow();
            end
           
            app.StatewindowEditField.Value = "Black plate deposited in rack";  
            
             % Set pickup path
             q1 = deg2rad([-45 50 0 -43.2 0 -104 43.5]);
             q2 = deg2rad([-45 55 0 -57.6 0 -107 43.5]);

             qWayPoints = [app.panda.model.getpos;q1;q2];
  
             app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));

             for i = 1:size(app.qMatrix,1)
                pandaQ = app.qMatrix(i,:);
                app.panda.model.animate(pandaQ);
                drawnow();
             end
   
            % Pick up and drop plate 
            q3 = deg2rad([-45 50 0 -43.2 0 -104 43.5]); 
            q4 = deg2rad([-131 32 0 -43.2 0 -104 130]);
            q5 = deg2rad([-131 60 0 -43.2 97.2 -144 14]); 

            qWayPoints = [q3;q4;q5];
  
            app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));
  
            for i = 1:size(app.qMatrix,1)
                pandaQ = app.qMatrix(i,:);
                app.panda.model.animate(pandaQ);
      
                tr = app.panda.model.fkine(pandaQ) * transl(0,-0.2085,0) * trotz(pi/2) * trotx(-pi/2);
                app.bplate_tverts = [app.bplate_verts, ones(size(app.bplate_verts, 1),1)] * tr';
                set(app.blackplate ,'Vertices',app.bplate_tverts(:,1:3)) 

                drawnow();
                pause(0.01);
            end

            app.bplate_tverts = [app.bplate_verts, ones(size(app.bplate_verts, 1),1)] * transl(-0.725,1.335,2.185)' * trotx(-pi/2);
            set(app.blackplate ,'Vertices',app.bplate_tverts(:,1:3)) 
  
            app.StatewindowEditField.Value = "Black Plate deposited";

            % Repose after drop 
            q7 = deg2rad([-131 32 0 -43.2 0 -104 130]);
            q8 = deg2rad([0 0 0 0 0 0 0]);

            qWayPoints = [q7;q8];
  
            app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));

            for i = 1:size(app.qMatrix,1)
                pandaQ = app.qMatrix(i,:);
                app.panda.model.animate(pandaQ);
                drawnow();
            end
        end

        % Image clicked function: Image5
        function Image5Clicked2(app, event)
            app.ResetButtonPushed;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.startupFcn;
        end

        % Button pushed function: RMRCControlButton
        function RMRCControlButtonPushed(app, event)
            % Initiate sequence
            app.StatewindowEditField.Value = "Starting RMRC sequence....";
            app.ur3StartQ = [app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]; % Reasonable initial guess for ikcon in control
            app.pandaStartQ = [app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6, app.pandaLink7];
            
            % Animate each joint configuration to the set values from the knobs
            app.ur3.model.animate(app.ur3StartQ);
            app.panda.model.animate(app.pandaStartQ);  

            % RMRC Trajectory
            app.plateHeight = 1.05;
            app.time = 10;                                                  % Total time (s)
            app.deltaT = 0.05;                                              % Control frequency
            app.ur3T1 = app.ur3.model.fkine(app.ur3StartQ);                 % Initial transform
            app.pandaT1 = app.panda.model.fkine(app.pandaStartQ);
            app.ur3T2 = transl(0.8,-1.4, app.plateHeight) * trotx(pi) * trotz(pi/2);  % Arm Up position (trot to rotate the end-effector orient
 
            app.resolvedMotionRateControl = true;                           % RMRC to Plate Position
            app.ur3control = RMRC(app.ur3, app.resolvedMotionRateControl);
            app.ur3control.ResolvedMotionRateControl(app.ur3T1,app.ur3T2,app.time,app.deltaT);  % towards plate
              
            % From plate to dish rack (ur3)
            ur3_T1update = app.ur3.model.fkine(app.ur3.model.getpos);
            app.ur3T3 = transl(0.1725,-2.0,1.3) * trotx(pi) * trotz(pi/2);
            app.ur3control.ResolvedMotionRateControl(ur3_T1update,app.ur3T3,app.time,app.deltaT);  % towards plate
            
            % Move up after drop
            ur3_T2update = app.ur3.model.fkine(app.ur3.model.getpos);
            app.ur3T4 = transl(0.8,-1.85,2);
            app.ur3control.ResolvedMotionRateControl(ur3_T2update,app.ur3T4,app.time,app.deltaT);  % towards plate
            app.StatewindowEditField.Value = "RMRC sequence finished";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GreenPlateButton = uibutton(app.UIFigure, 'push');
            app.GreenPlateButton.ButtonPushedFcn = createCallbackFcn(app, @GreenPlateButtonPushed, true);
            app.GreenPlateButton.Text = 'Green Plate';
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'EStop';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = 'Estop.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UR3LinksTab = uitab(app.TabGroup);
            app.UR3LinksTab.Title = 'UR3 Links';
            app.Link1KnobLabel = uilabel(app.UR3LinksTab);
            app.Link1KnobLabel.Text = 'Link 1';
            app.Link1Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link1Knob.Limits = [-360 360];
            app.Link1Knob.ValueChangingFcn = createCallbackFcn(app, @Link1KnobValueChanging, true);
            app.Link2KnobLabel = uilabel(app.UR3LinksTab);
            app.Link2KnobLabel.Text = 'Link 2';
            app.Link2Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link2Knob.Limits = [-360 360];
            app.Link2Knob.ValueChangingFcn = createCallbackFcn(app, @Link2KnobValueChanging, true);
            app.Link3KnobLabel = uilabel(app.UR3LinksTab);
            app.Link3KnobLabel.Text = 'Link 3';
            app.Link3Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link3Knob.Limits = [-360 360];
            app.Link3Knob.ValueChangingFcn = createCallbackFcn(app, @Link3KnobValueChanging, true);
            app.Link4KnobLabel = uilabel(app.UR3LinksTab);
            app.Link4KnobLabel.Text = 'Link 4';
            app.Link4Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link4Knob.Limits = [-360 360];
            app.Link4Knob.ValueChangingFcn = createCallbackFcn(app, @Link4KnobValueChanging, true);
            app.Link5KnobLabel = uilabel(app.UR3LinksTab);
            app.Link5KnobLabel.Text = 'Link 5';
            app.Link5Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link5Knob.Limits = [-360 360];
            app.Link5Knob.ValueChangingFcn = createCallbackFcn(app, @Link5KnobValueChanging, true);
            app.Link6KnobLabel = uilabel(app.UR3LinksTab);
            app.Link6KnobLabel.Text = 'Link 6';
            app.Link6Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link6Knob.Limits = [-360 360];
            app.Link6Knob.ValueChangingFcn = createCallbackFcn(app, @Link6KnobValueChanging, true);
            app.PandaLinksTab = uitab(app.TabGroup);
            app.PandaLinksTab.Title = 'Panda Links';
            app.Link1Knob_2Label = uilabel(app.PandaLinksTab);
            app.Link1Knob_2Label.Text = 'Link 1';
            app.Link1Knob_2 = uiknob(app.PandaLinksTab, 'continuous');
            app.Link1Knob_2.Limits = [-157 157];
            app.Link1Knob_2.MajorTicks = [-157 -100 -50 0 50 100 157];
            app.Link1Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link1Knob_2ValueChanging, true);
            app.Link2Knob_2Label = uilabel(app.PandaLinksTab);
            app.Link2Knob_2Label.Text = 'Link 2';
            app.Link2Knob_2 = uiknob(app.PandaLinksTab, 'continuous');
            app.Link2Knob_2.Limits = [-102 102];
            app.Link2Knob_2.MajorTicks = [-102 -75 -50 0 50 75 102];
            app.Link2Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link2Knob_2ValueChanging, true);
            app.Link3Knob_2Label = uilabel(app.PandaLinksTab);
            app.Link3Knob_2Label.Text = 'Link 3';
            app.Link3Knob_2 = uiknob(app.PandaLinksTab, 'continuous');
            app.Link3Knob_2.Limits = [-166 166];
            app.Link3Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link3Knob_2ValueChanging, true);
            app.Link4Knob_2Label = uilabel(app.PandaLinksTab);
            app.Link4Knob_2Label.Text = 'Link 4';
            app.Link4Knob_2 = uiknob(app.PandaLinksTab, 'continuous');
            app.Link4Knob_2.Limits = [-174 174];
            app.Link4Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link4Knob_2ValueChanging, true);
            app.Link5Knob_2Label = uilabel(app.PandaLinksTab);
            app.Link5Knob_2Label.Text = 'Link 5';
            app.Link5Knob_2 = uiknob(app.PandaLinksTab, 'continuous');
            app.Link5Knob_2.Limits = [-161 161];
            app.Link5Knob_2.MajorTicks = [-161 -75 0 75 161];
            app.Link5Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link5Knob_2ValueChanging, true);
            app.Link6Knob_2Label = uilabel(app.PandaLinksTab);
            app.Link6Knob_2Label.Text = 'Link 6';
            app.Link6Knob_2 = uiknob(app.PandaLinksTab, 'continuous');
            app.Link6Knob_2.Limits = [-259 259];
            app.Link6Knob_2.MajorTicks = [-259 -150 0 150 259];
            app.Link6Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link6Knob_2ValueChanging, true);
            app.Link7KnobLabel = uilabel(app.PandaLinksTab);
            app.Link7KnobLabel.Text = 'Link 7';
            app.Link7Knob = uiknob(app.PandaLinksTab, 'continuous');
            app.Link7Knob.Limits = [-173 173];
            app.Link7Knob.MajorTicks = [-173 -120 -50 0 50 120 173];
            app.Link7Knob.ValueChangingFcn = createCallbackFcn(app, @Link7KnobValueChanging, true);
            app.UR3CartesianControlTab = uitab(app.TabGroup);
            app.UR3CartesianControlTab.Title = 'UR3 Cartesian Control';
            app.XSliderLabel = uilabel(app.UR3CartesianControlTab);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UR3CartesianControlTab);
            app.XSlider.Limits = [-2.5 2.5];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.YSliderLabel = uilabel(app.UR3CartesianControlTab);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UR3CartesianControlTab);
            app.YSlider.Limits = [-2.5 2.5];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.ZSliderLabel = uilabel(app.UR3CartesianControlTab);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UR3CartesianControlTab);
            app.ZSlider.Limits = [-2.5 2.5];
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.PandaCartesianControlTab = uitab(app.TabGroup);
            app.PandaCartesianControlTab.Title = 'Panda Cartesian Control';
            app.XSlider_2Label = uilabel(app.PandaCartesianControlTab);
            app.XSlider_2Label.Text = 'X';
            app.XSlider_2 = uislider(app.PandaCartesianControlTab);
            app.XSlider_2.Limits = [-2.5 2.5];
            app.XSlider_2.ValueChangingFcn = createCallbackFcn(app, @XSlider_2ValueChanging, true);
            app.YSlider_2Label = uilabel(app.PandaCartesianControlTab);
            app.YSlider_2Label.Text = 'Y';
            app.YSlider_2 = uislider(app.PandaCartesianControlTab);
            app.YSlider_2.Limits = [-2.5 2.5];
            app.YSlider_2.ValueChangingFcn = createCallbackFcn(app, @YSlider_2ValueChanging, true);
            app.ZSlider_2Label = uilabel(app.PandaCartesianControlTab);
            app.ZSlider_2Label.Text = 'Z';
            app.ZSlider_2 = uislider(app.PandaCartesianControlTab);
            app.ZSlider_2.Limits = [-2.5 2.5];
            app.ZSlider_2.ValueChangingFcn = createCallbackFcn(app, @ZSlider_2ValueChanging, true);
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.ShutDownButton = uibutton(app.UIFigure, 'push');
            app.ShutDownButton.ButtonPushedFcn = createCallbackFcn(app, @ShutDownButtonPushed, true);
            app.ShutDownButton.Text = 'Shut Down';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'Shutdown.png';
            app.ControllerStateLamp = uilamp(app.UIFigure);
            app.StatewindowEditField = uieditfield(app.UIFigure, 'text');
            app.EstopTriggerLamp = uilamp(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'SafeCo.png';
            app.BlackPlateButton = uibutton(app.UIFigure, 'push');
            app.BlackPlateButton.ButtonPushedFcn = createCallbackFcn(app, @BlackPlateButtonPushed, true);
            app.BlackPlateButton.Text = 'Black Plate';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed2, true);
            app.ResumeButton.Text = 'Resume';
            app.ObjectPositionButtonGroup = uibuttongroup(app.UIFigure);
            app.ObjectPositionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ObjectPositionButtonGroupSelectionChanged, true);
            app.ObjectPositionButtonGroup.Title = 'Object Position';
            app.xButton = uiradiobutton(app.ObjectPositionButtonGroup);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.ObjectPositionButtonGroup);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.ObjectPositionButtonGroup);
            app.zButton.Text = 'z';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'Play_button.png';
            app.CollisionDetectionButton = uibutton(app.UIFigure, 'state');
            app.CollisionDetectionButton.ValueChangedFcn = createCallbackFcn(app, @CollisionDetectionButtonValueChanged, true);
            app.CollisionDetectionButton.Text = 'Collision Detection';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '-';
            app.RMRCControlButton = uibutton(app.UIFigure, 'push');
            app.RMRCControlButton.ButtonPushedFcn = createCallbackFcn(app, @RMRCControlButtonPushed, true);
            app.RMRCControlButton.Text = 'RMRC Control';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked2, true);
            app.Image5.ImageSource = 'reset.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end